classdef Minesweeper < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        ChoosedifficultyDropDown       matlab.ui.control.DropDown
        ChoosedifficultyDropDownLabel  matlab.ui.control.Label
    end

    
    methods (Access = public)
        
        function generateBomb(~,field,difficulty)
            [i,j]=size(field);
            for k=1:difficulty
                randRow=randi([1,i],1);
                randCol=randi([1,j],1);
                if(field(randRow, randCol)==0)
                    field(randRow, randCol)=1;
                end

            end
            disp(field);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartGame(app, event)
            difficulty=app.ChoosedifficultyDropDown.Value;
            switch difficulty
                case 'Medium'
                    field=zeros(16);
                    generateBomb(app,field,40);
                case 'Hard'
                    field=zeros(16,30);
                    generateBomb(app,field,99);
                otherwise
                    field=zeros(8);
                    generateBomb(app,field,10);
            
            end
            
            MainGame
            app.UIFigure.Visible='off';

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChoosedifficultyDropDownLabel = uilabel(app.UIFigure);
            app.ChoosedifficultyDropDownLabel.Text = 'Choose difficulty';
            app.ChoosedifficultyDropDown = uidropdown(app.UIFigure);
            app.ChoosedifficultyDropDown.Items = {'Easy', 'Medium', 'Hard'};
            app.ChoosedifficultyDropDown.Placeholder = '...';
            app.ChoosedifficultyDropDown.Value = 'Easy';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartGame, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Minesweeper

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end